classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        StatusLamp             matlab.ui.control.Lamp
        StatusLampLabel        matlab.ui.control.Label
        ResumeButton           matlab.ui.control.Button
        ESTOPButton            matlab.ui.control.StateButton
        STARTSIMULATIONButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTSIMULATIONButton
        function STARTSIMULATIONButtonPushed(app, event)
            % Start the timer to check ESTOP status
            if app.ESTOPButton.Value == false
                Main; % Call your main function to start the simulation
                disp('Simulation started.');
            end
        end

        % Value changed function: ESTOPButton
        function ESTOPButtonValueChanged(app, event)
  value = app.ESTOPButton.Value;

            % Update the status lamp color based on ESTOP status
            if value
                app.StatusLamp.Color = [1, 0, 0]; % Red for stop
                disp('ESTOP activated, halting operations.');
            else
                app.StatusLamp.Color = [0, 1, 0]; % Green for resume
            end

                        % Check if ESTOP is activated
            while app.ESTOPButton.Value
                pause(0.1);

            end
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            % Reset the ESTOP button and start the simulation if stopped
            app.ESTOPButton.Value = false;
            app.StatusLamp.Color = [0, 1, 0]; % Green for resume
            disp('Simulation resumed.');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.STARTSIMULATIONButton = uibutton(app.UIFigure, 'push');
            app.STARTSIMULATIONButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSIMULATIONButtonPushed, true);
            app.STARTSIMULATIONButton.Text = 'START SIMULATION';
            app.ESTOPButton = uibutton(app.UIFigure, 'state');
            app.ESTOPButton.ValueChangedFcn = createCallbackFcn(app, @ESTOPButtonValueChanged, true);
            app.ESTOPButton.Text = 'ESTOP';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end