classdef controlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ResetButton               matlab.ui.control.Button
        RMRCControlButton         matlab.ui.control.Button
        CollisionDetectionButton  matlab.ui.control.StateButton
        ResumeButton              matlab.ui.control.Button
        BlackPlateButton          matlab.ui.control.Button
        EstopTriggerLamp          matlab.ui.control.Lamp
        StatewindowEditField      matlab.ui.control.EditField
        ControllerStateLamp       matlab.ui.control.Lamp
        ShutDownButton            matlab.ui.control.Button
        AryztaPicknPackControlPanelLabel  matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        UR3LinksTab               matlab.ui.container.Tab
        Link6Knob                 matlab.ui.control.Knob
        Link6KnobLabel            matlab.ui.control.Label
        Link5Knob                 matlab.ui.control.Knob
        Link5KnobLabel            matlab.ui.control.Label
        Link4Knob                 matlab.ui.control.Knob
        Link4KnobLabel            matlab.ui.control.Label
        Link3Knob                 matlab.ui.control.Knob
        Link3KnobLabel            matlab.ui.control.Label
        Link2Knob                 matlab.ui.control.Knob
        Link2KnobLabel            matlab.ui.control.Label
        Link1Knob                 matlab.ui.control.Knob
        Link1KnobLabel            matlab.ui.control.Label
        UR30LinksTab              matlab.ui.container.Tab
        Link6KnobLabel_2          matlab.ui.control.Label
        Link5KnobLabel_2          matlab.ui.control.Label
        Link4KnobLabel_2          matlab.ui.control.Label
        Link3KnobLabel_2          matlab.ui.control.Label
        Link2KnobLabel_2          matlab.ui.control.Label
        Link1Knob_2               matlab.ui.control.Knob
        Link1Knob_2Label          matlab.ui.control.Label
        Link6Knob_2               matlab.ui.control.Knob
        Link5Knob_2               matlab.ui.control.Knob
        Link4Knob_2               matlab.ui.control.Knob
        Link3Knob_2               matlab.ui.control.Knob
        Link2Knob_2               matlab.ui.control.Knob
        UR3CartesianControlTab    matlab.ui.container.Tab
        ZSlider                   matlab.ui.control.Slider
        ZSliderLabel              matlab.ui.control.Label
        YSlider                   matlab.ui.control.Slider
        YSliderLabel              matlab.ui.control.Label
        XSlider                   matlab.ui.control.Slider
        XSliderLabel              matlab.ui.control.Label
        UR3ECartesianControlTab   matlab.ui.container.Tab
        ZSlider_2                 matlab.ui.control.Slider
        ZSlider_2Label            matlab.ui.control.Label
        YSlider_2                 matlab.ui.control.Slider
        YSlider_2Label            matlab.ui.control.Label
        XSlider_2                 matlab.ui.control.Slider
        XSlider_2Label            matlab.ui.control.Label
        EStopButton               matlab.ui.control.Button
        GreenPlateButton          matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

properties (Access = public)
     % UR3 Robot Properties
    ur3;                % UR3 robot model instance
    ur3StartQ;          % Initial joint configuration for UR3
    ur3UpdateQ;         % Updated joint configuration for UR3 during motion
    ur3velocityx = 0;   % X-direction velocity for UR3
    ur3velocityy = 0;   % Y-direction velocity for UR3
    ur3velocityz = 0;   % Z-direction velocity for UR3
    ur3Link1 = 0;       % UR3 joint angle for link 1
    ur3Link2 = 0;       % UR3 joint angle for link 2
    ur3Link3 = 0;       % UR3 joint angle for link 3
    ur3Link4 = 0;       % UR3 joint angle for link 4
    ur3Link5 = 0;       % UR3 joint angle for link 5
    ur3Link6 = 0;       % UR3 joint angle for link 6
    ur3centrepoints;    % Collision check center points for UR3 links
    ur3radii;           % Collision check radii for UR3 links

    % UR30 Robot Properties
    ur30;               % UR30 robot model instance
    ur30StartQ;         % Initial joint configuration for UR30
    ur30UpdateQ;        % Updated joint configuration for UR30 during motion
    ur30velocityx = 0;  % X-direction velocity for UR30
    ur30velocityy = 0;  % Y-direction velocity for UR30
    ur30velocityz = 0;  % Z-direction velocity for UR30
    ur30Link1 = 0;      % UR30 joint angle for link 1
    ur30Link2 = 0;      % UR30 joint angle for link 2
    ur30Link3 = 0;      % UR30 joint angle for link 3
    ur30Link4 = 0;      % UR30 joint angle for link 4
    ur30Link5 = 0;      % UR30 joint angle for link 5
    ur30Link6 = 0;      % UR30 joint angle for link 6
    ur30Link7 = 0;      % UR30 joint angle for link 7
    ur30centrepoints;   % Collision check center points for UR30 links
    ur30radii;          % Collision check radii for UR30 links

    % Environment Variables
    workspace;           % Reference to the overall workspace object
    groundImage;         % Ground texture (e.g., 'concrete.jpg')
    wallImages;          % Array for wall textures (e.g., {'FactoryWall.png', 'ExitWall.png'})
    cameraSettings;      % Structure to hold camera settings

    % Interaction Points
    pickupPoints;        % Array of pickup locations for objects
    dropoffPoints;       % Array of drop-off locations
    robotBasePositions;  % Starting positions for each robot

    % Plates and Objects
    greenPlate;          % Green plate object handle
    greenPlateVerts;     % Vertices for the green plate
    greenPlateTransform; % Transformation matrix for the green plate

    blackPlate;          % Black plate object handle
    blackPlateVerts;     % Vertices for the black plate
    blackPlateTransform; % Transformation matrix for the black plate

    % Scene Management
    plates;              % Structure/array of all plate objects
    objectsInScene;      % Array of other objects in the environment

    % Collision and Control
    collisionObjects;    % Array of objects to check for collision
    collisionCheckActive = false; % Enable/disable collision detection
    emergencyStop = false;        % Emergency stop flag
    statusIndicators;    % Status indicators (lamps, messages)
end
    
    methods (Access = private)
        
        % Reset function to initialize or reset app components
        function resetApp(app)
        % Add your initialization and reset code here
        % For example:
        app.StatewindowEditField.Value = "Resetting app...";
        
        % Configure UIAxes (replace with the correct name if needed)
        if isprop(app, 'UIAxes') && ~isempty(app.UIAxes)
            app.UIAxes.XLim = [-10, 4];
            app.UIAxes.YLim = [-4, 4];
            app.UIAxes.ZLim = [0, 3];
            view(app.UIAxes, 3);
            camlight(app.UIAxes, 'headlight');
        end
        
        % Additional initialization or reset steps
        % ...
        
        app.StatewindowEditField.Value = "App reset completed.";
    end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ur3 = mdl_ur3;
            % Configure the UIAxes for 3D viewing in the environment
            app.UIAxes.XLim = [-10, 4];
            app.UIAxes.YLim = [-4, 4];
            app.UIAxes.ZLim = [0, 3];
            view(app.UIAxes, 3);
            camlight(app.UIAxes, 'headlight');
        
            % Initialize camera settings
            app.cameraSettings = struct('xlim', [-10, 4], 'ylim', [-4, 4], 'zlim', [0, 3]);
        
            % Load and configure the environment using PlotEnvironment
            PlotEnvironment(app.UIAxes); % Modify PlotEnvironment to accept UIAxes as an input
        
            % Set up base positions for UR3 and UR30 robots
            app.robotBasePositions.UR3 = transl(0.8, -1.85, 1.0) * trotz(pi);
            app.robotBasePositions.UR30 = transl(-0.15, -1.45, 0.995);
        
            % Initialize and plot the UR3 and UR30 robots in the environment
            app.UR3 = UR3(app.robotBasePositions.UR3);
            app.UR30 = UR30(app.robotBasePositions.UR30);
            app.UR3.PlotAndColourUR3(app.UIAxes);
            app.UR30.PlotAndColourUR30(app.UIAxes);
        
            % Define pickup and dropoff points for objects
            app.pickupPoints = {transl(1.0, -1.5, 0.75), transl(1.2, -1.3, 0.75)};
            app.dropoffPoints = {transl(-0.5, -1.2, 1.0), transl(-0.7, -1.4, 1.0)};
        
            % Load and position green and black plates
            % Green Plate
            app.greenPlate = PlaceObject('plate.PLY'); % Replace with actual file name
            app.greenPlateVerts = get(app.greenPlate, 'Vertices');
            app.greenPlateTransform = transl(0.75, -1.525, 0.775); % Initial position for green plate
            gplate_tverts = [app.greenPlateVerts, ones(size(app.greenPlateVerts, 1), 1)] * app.greenPlateTransform';
            set(app.greenPlate, 'Vertices', gplate_tverts(:,1:3));
        
            % Black Plate
            app.blackPlate = PlaceObject('plateblack.ply'); % Replace with actual file name
            app.blackPlateVerts = get(app.blackPlate, 'Vertices');
            app.blackPlateTransform = transl(0.75, -1.45, 0.775); % Initial position for black plate
            bplate_tverts = [app.blackPlateVerts, ones(size(app.blackPlateVerts, 1), 1)] * app.blackPlateTransform';
            set(app.blackPlate, 'Vertices', bplate_tverts(:,1:3));
        
            % Initialize the array of plates and other objects in the scene
            app.plates = {app.greenPlate, app.blackPlate};
            app.objectsInScene = [app.plates]; % Add other objects if necessary
        
            % Set up collision objects and enable collision checking
            app.collisionObjects = [app.greenPlate, app.blackPlate]; % Add other potential obstacles
            app.collisionCheckActive = true; % Enable collision detection
        
            % Initialize status indicators (e.g., for collision detection)
            app.statusIndicators = struct('EStopLamp', app.EstopTriggerLamp, 'ControllerStateLamp', app.ControllerStateLamp);
        
            % Display initial status in the control panel
            app.StatewindowEditField.Value = "System initialized. Ready for operation.";
        end

        % Value changing function: Link6Knob
        function Link6KnobValueChanging(app, event)
            % Get the current value of the knob (in degrees) and convert to radians
            angle = deg2rad(event.Value);
            
            % Update the UR3's sixth joint angle
            app.UR3.jointAngles(6) = angle;
            
            % Animate the UR3 robot with the updated joint angles
            app.UR3.animate(app.UR3.jointAngles);
            
            % Update the state window or any other relevant UI element if needed
            app.StatewindowEditField.Value = sprintf('UR3 Link 6 Angle: %.2f°', rad2deg(angle));
        end

        % Value changing function: Link5Knob
        function Link5KnobValueChanging(app, event)
            % Get the current value of the knob (in degrees) and convert to radians
            angle = deg2rad(event.Value);
            
            % Update the UR3's fifth joint angle
            app.UR3.jointAngles(5) = angle;
            
            % Animate the UR3 robot with the updated joint angles
            app.UR3.animate(app.UR3.jointAngles);
            
            % Update the state window or any other relevant UI element if needed
            app.StatewindowEditField.Value = sprintf('UR3 Link 5 Angle: %.2f°', rad2deg(angle));
        end

        % Value changing function: Link4Knob
        function Link4KnobValueChanging(app, event)
            % Get the current value of the knob (in degrees) and convert to radians
            angle = deg2rad(event.Value);
            
            % Update the UR3's fifth joint angle
            app.UR3.jointAngles(4) = angle;
            
            % Animate the UR3 robot with the updated joint angles
            app.UR3.animate(app.UR3.jointAngles);
            
            % Update the state window or any other relevant UI element if needed
            app.StatewindowEditField.Value = sprintf('UR3 Link 4 Angle: %.2f°', rad2deg(angle));
        end

        % Value changing function: Link3Knob
        function Link3KnobValueChanging(app, event)
            % Get the current value of the knob (in degrees) and convert to radians
            angle = deg2rad(event.Value);
            
            % Update the UR3's fifth joint angle
            app.UR3.jointAngles(3) = angle;
            
            % Animate the UR3 robot with the updated joint angles
            app.UR3.animate(app.UR3.jointAngles);
            
            % Update the state window or any other relevant UI element if needed
            app.StatewindowEditField.Value = sprintf('UR3 Link 3 Angle: %.2f°', rad2deg(angle));
        end

        % Value changing function: Link2Knob
        function Link2KnobValueChanging(app, event)
            % Get the current value of the knob (in degrees) and convert to radians
            angle = deg2rad(event.Value);
            
            % Update the UR3's fifth joint angle
            app.UR3.jointAngles(2) = angle;
            
            % Animate the UR3 robot with the updated joint angles
            app.UR3.animate(app.UR3.jointAngles);
            
            % Update the state window or any other relevant UI element if needed
            app.StatewindowEditField.Value = sprintf('UR3 Link 2 Angle: %.2f°', rad2deg(angle));
        end

        % Value changing function: Link1Knob
        function Link1KnobValueChanging(app, event)
            % Get the current value of the knob (in degrees) and convert to radians
            angle = deg2rad(event.Value);
            
            % Update the UR3's fifth joint angle
            app.UR3.jointAngles(1) = angle;
            
            % Animate the UR3 robot with the updated joint angles
            app.UR3.animate(app.UR3.jointAngles);
            
            % Update the state window or any other relevant UI element if needed
            app.StatewindowEditField.Value = sprintf('UR3 Link 1 Angle: %.2f°', rad2deg(angle));
        end

        % Value changing function: Link6Knob_2
        function Link6Knob_2ValueChanging(app, event)
            % Get the current value of the knob (in degrees) and convert to radians
            angle = deg2rad(event.Value);
            
            % Update the UR30's sixth joint angle
            app.UR30.jointAngles(6) = angle;
            
            % Animate the UR30 robot with the updated joint angles
            app.UR30.animate(app.UR30.jointAngles);
            
            % Update the state window or any other relevant UI element if needed
            app.StatewindowEditField.Value = sprintf('UR30 Link 6 Angle: %.2f°', rad2deg(angle));
        end

        % Value changing function: Link5Knob_2
        function Link5Knob_2ValueChanging(app, event)
            % Get the current value of the knob (in degrees) and convert to radians
            angle = deg2rad(event.Value);
            
            % Update the UR30's sixth joint angle
            app.UR30.jointAngles(5) = angle;
            
            % Animate the UR30 robot with the updated joint angles
            app.UR30.animate(app.UR30.jointAngles);
            
            % Update the state window or any other relevant UI element if needed
            app.StatewindowEditField.Value = sprintf('UR30 Link 5 Angle: %.2f°', rad2deg(angle));
        end

        % Value changing function: Link4Knob_2
        function Link4Knob_2ValueChanging(app, event)
            % Get the current value of the knob (in degrees) and convert to radians
            angle = deg2rad(event.Value);
            
            % Update the UR30's sixth joint angle
            app.UR30.jointAngles(4) = angle;
            
            % Animate the UR30 robot with the updated joint angles
            app.UR30.animate(app.UR30.jointAngles);
            
            % Update the state window or any other relevant UI element if needed
            app.StatewindowEditField.Value = sprintf('UR30 Link 4 Angle: %.2f°', rad2deg(angle));
        end

        % Value changing function: Link3Knob_2
        function Link3Knob_2ValueChanging(app, event)
            % Get the current value of the knob (in degrees) and convert to radians
            angle = deg2rad(event.Value);
            
            % Update the UR30's sixth joint angle
            app.UR30.jointAngles(3) = angle;
            
            % Animate the UR30 robot with the updated joint angles
            app.UR30.animate(app.UR30.jointAngles);
            
            % Update the state window or any other relevant UI element if needed
            app.StatewindowEditField.Value = sprintf('UR30 Link 3 Angle: %.2f°', rad2deg(angle));
        end

        % Value changing function: Link2Knob_2
        function Link2Knob_2ValueChanging(app, event)
            % Get the current value of the knob (in degrees) and convert to radians
            angle = deg2rad(event.Value);
            
            % Update the UR30's sixth joint angle
            app.UR30.jointAngles(2) = angle;
            
            % Animate the UR30 robot with the updated joint angles
            app.UR30.animate(app.UR30.jointAngles);
            
            % Update the state window or any other relevant UI element if needed
            app.StatewindowEditField.Value = sprintf('UR30 Link 2 Angle: %.2f°', rad2deg(angle));
        end

        % Value changing function: Link1Knob_2
        function Link1Knob_2ValueChanging(app, event)
            % Get the current value of the knob (in degrees) and convert to radians
            angle = deg2rad(event.Value);
            
            % Update the UR30's sixth joint angle
            app.UR30.jointAngles(1) = angle;
            
            % Animate the UR30 robot with the updated joint angles
            app.UR30.animate(app.UR30.jointAngles);
            
            % Update the state window or any other relevant UI element if needed
            app.StatewindowEditField.Value = sprintf('UR30 Link 1 Angle: %.2f°', rad2deg(angle));
        end

        % Button pushed function: GreenPlateButton
        function GreenPlateButtonPushed(app, event)
             % Update the state window to indicate the start of the sequence
            app.StatewindowEditField.Value = "Starting sequence to pick up Green Plate...";
        
            % Define the waypoints for the pick-and-place sequence
            % Set the initial position, approach, pick, and drop positions
            startPos = app.UR3.model.getpos(); % Current position of UR3
            approachPos = app.pickupPoints{1} * trotx(pi); % Adjust approach position near the green plate
            pickPos = app.pickupPoints{1}; % Exact pickup position for the green plate
            dropPos = app.dropoffPoints{1}; % Drop-off position for the green plate
        
            % Define waypoints for each phase of the sequence
            waypoints = {startPos, approachPos, pickPos, dropPos};
            
            % Interpolate and animate the UR3 moving through each waypoint
            for i = 1:length(waypoints) - 1
                qMatrix = InterpolateWayPointRadians(waypoints{i}, waypoints{i+1}, deg2rad(5)); % Customize interpolation as needed
                for q = qMatrix'
                    app.UR3.animate(q');
                    pause(0.1); % Adjust the pause for animation speed
                end
            end
            
            % Move the green plate with the end effector to the drop-off point
            tr = app.UR3.model.fkine(dropPos) * transl(0, 0, 0.05); % Positioning for the plate to move with UR3
            app.greenPlateVerts = get(app.greenPlate, 'Vertices'); % Get the vertices of the green plate
            gplate_tverts = [app.greenPlateVerts, ones(size(app.greenPlateVerts, 1), 1)] * tr';
            set(app.greenPlate, 'Vertices', gplate_tverts(:, 1:3));
        
            % Update the state window to indicate completion of the sequence
            app.StatewindowEditField.Value = "Green Plate placed successfully.";
        end

        % Button pushed function: ShutDownButton
        function ShutDownButtonPushed(app, event)
            % Update the state window to indicate the system is shutting down
            app.StatewindowEditField.Value = "Shutting down system...";
            pause(1); % Optional pause for visual feedback
        
            % Stop any ongoing processes or animations
            app.emergencyStop = true; % Set emergency stop flag to prevent further movement
            
            % Clear all objects in the UIAxes
            cla(app.UIAxes, 'reset'); % Clears the axes and resets properties
        
            % Clear other application properties related to robots and environment
            app.UR3 = []; % Clear UR3 robot instance
            app.UR30 = []; % Clear UR30 robot instance
            app.greenPlate = []; % Clear green plate
            app.blackPlate = []; % Clear black plate
            app.plates = []; % Clear plate array
            app.objectsInScene = []; % Clear any other objects in the scene
        
            % Update the state window to confirm shutdown completion
            app.StatewindowEditField.Value = "System shut down. You may close the application.";
            
            % Optionally, close the application window
            pause(1); % Optional pause for visual feedback
            delete(app.UIFigure); % Close the application
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            % Set the emergency stop flag to true to halt any ongoing operations
            app.emergencyStop = true;
            
            % Update the state window to inform the user that the E-Stop is activated
            app.StatewindowEditField.Value = "Emergency Stop Activated!";
            
            % Change the EStop trigger lamp color to red to visually indicate the E-Stop state
            app.EstopTriggerLamp.Color = [1, 0, 0]; % Red color
            
            % Optionally, reset any in-progress animations or sequences
            % This could involve stopping movement in any interpolated path functions
            if isfield(app, 'UR3') && ~isempty(app.UR3)
                % Reset UR3 to its current position to immediately stop movement
                app.UR3.animate(app.UR3.jointAngles);
            end
            
            if isfield(app, 'UR30') && ~isempty(app.UR30)
                % Reset UR30 to its current position to immediately stop movement
                app.UR30.animate(app.UR30.jointAngles);
            end
            
            % Display final confirmation in the status window
            pause(0.5); % Optional small delay for user feedback
            app.StatewindowEditField.Value = "System halted. Please reset to resume operations.";
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ur3velocityz= 0.15 * changingValue;
            dt = 0.30;
            dx = [0;0;app.ur3velocityz;0;0;0]; % [x,y,z,roll,pitch,yaw]
            q = app.ur3.model.getpos;
            J = app.ur3.model.jacob0(q);
            dq = pinv(J)* dx;
            q = q + dq'*dt;
            app.ur3.model.animate(q);
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ur3velocityy= 0.15 * changingValue;
            dt = 0.30;
            dx = [0;app.ur3velocityy;0;0;0;0]; % [x,y,z,roll,pitch,yaw]
            q = app.ur3.model.getpos;
            J = app.ur3.model.jacob0(q);
            dq = pinv(J)* dx;
            q = q + dq'*dt;
            app.ur3.model.animate(q);
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ur3velocityx= 0.15 * changingValue;
            dt = 0.30;
            dx = [app.ur3velocityx;0;0;0;0;0]; % [x,y,z,roll,pitch,yaw]
            q = app.ur3.model.getpos;
            J = app.ur3.model.jacob0(q);
            dq = pinv(J)* dx;
            q = q + dq'*dt;
            app.ur3.model.animate(q);
        end

        % Value changing function: ZSlider_2
        function ZSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.ur30velocityz= 0.15 * changingValue;
            dt = 0.30;
            dx = [0;0;app.ur30velocityz;0;0;0]; % [x,y,z,roll,pitch,yaw]
            q = app.ur30.model.getpos;
            J = app.ur30.model.jacob0(q);
            dq = pinv(J) * dx;
            q = q + dq'*dt;
            app.ur30.model.animate(q);
        end

        % Value changing function: YSlider_2
        function YSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.ur30velocityy= 0.15 * changingValue;
            dt = 0.30;
            dx = [0;app.ur30velocityy;0;0;0;0]; % [x,y,z,roll,pitch,yaw]
            q = app.ur30.model.getpos;
            J = app.ur30.model.jacob0(q);
            dq = pinv(J)* dx;
            q = q + dq'*dt;
            app.ur30.model.animate(q);
        end

        % Value changing function: XSlider_2
        function XSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.ur30velocityx= 0.15 * changingValue;
            dt = 0.30;
            dx = [app.ur30velocityx;0;0;0;0;0];
            q = app.ur30.model.getpos;
            J = app.ur30.model.jacob0(q);
            dq = pinv(J)* dx;
            q = q + dq'*dt;
            app.ur30.model.animate(q);
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed2(app, event)
            if app.collisionOn == 0
                        app.ur3control = RMRC(app.ur3, true); % Keep looping through trajectory with RMRC
                        app.StatewindowEditField.Value = "Resuming operations...";
                        set(gcf,'color','w'); % Set background color to red
                        app.EstopTriggerLamp.Color = [0.7 0.7 0.7]; % Gray
            end
        end

        % Value changed function: CollisionDetectionButton
        function CollisionDetectionButtonValueChanged(app, event)
            % Spawn detection object into the environment
            event = app.CollisionDetectionButton.Value;
            if event == 1
                app.StatewindowEditField.Value = "Collision Object Active";
                app.collisionOn = 1;
                app.collisionObj.updatePlot();
            else
                app.StatewindowEditField.Value = "Collision Object Deactivated";
                app.collisionOn = 0;
                app.collisionObj.removePlots();
            end
        end

        % Button pushed function: BlackPlateButton
        function BlackPlateButtonPushed(app, event)
            app.StatewindowEditField.Value = "Starting sequence to pickup black plate";
            app.ur3StartQ = [app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]; % Reasonable initial guess for ikcon in control
            app.ur30StartQ = [app.ur30Link1,app.ur30Link2,app.ur30Link3,app.ur30Link4,app.ur30Link5,app.ur30Link6, app.ur30Link7];
            
            % Animate each joint configuration to the set values from the knobs
            app.ur3.model.animate(app.ur3StartQ);
            app.ur30.model.animate(app.ur30StartQ);
            
            % Animate fingers
            iqFingerStart = deg2rad([0 0]);
            iqFingerOpen = deg2rad([-6.5 0]);
            iqFingerClose = deg2rad([22 10.5]);
            
            % Fine Interpolation 
            % UR3
            q1 = deg2rad([45 -27.5 50 70 90 0]);
            q2 = deg2rad([81.5 -20.4 28.4 59.2 90 0]);
            q3 = deg2rad([81.5 -6 28.4 51 90 0]);

            qWayPoints = [app.ur3.model.getpos;q1;q2;q3];
            iqOpenWayPoints = [iqFingerStart;iqFingerOpen];
            iqCloseWayPoints = [iqFingerOpen;iqFingerClose];
  
            app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));
            openMatrix = InterpolateWayPointRadians(iqOpenWayPoints, deg2rad(2));
            closeMatrix = InterpolateWayPointRadians(iqCloseWayPoints, deg2rad(2));

            app.ur3finger1.model.animate(openMatrix);
            app.ur3finger2.model.animate(openMatrix); % Open fingers
            drawnow();
            
            for i = 1:size(app.qMatrix,1)
                ur3Q = app.qMatrix(i,:);
                app.ur3.model.animate(ur3Q);
                app.ur3finger1.model.base = app.ur3.model.fkine(ur3Q) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger1.model.animate(app.ur3finger1.model.getpos);   
                app.ur3finger2.model.base = app.ur3.model.fkine(ur3Q)* trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger2.model.animate(app.ur3finger2.model.getpos);
                drawnow();
            end
            
            app.ur3finger1.model.animate(closeMatrix);
            app.ur3finger2.model.animate(closeMatrix); % Close Fingers
            drawnow();
  
            % Pick up and drop plate 
            q4 = deg2rad([81.5 -20.4 28.4 59.2 90 0]);
            q5 = deg2rad([168 -81.0 57.2 93.6 90 86.6]);
            q6 = deg2rad([189 -37.8 -7.6 112 90 86.6]);
            q7 = deg2rad([189 -20 -14.8 112 90 86.6]); 
  
            qWayPoints = [q4;q5;q6;q7];
  
            app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));
  
            for i = 1:size(app.qMatrix,1)
                ur3Q = app.qMatrix(i,:);
                app.ur3.model.animate(ur3Q);
      
                app.ur3finger1.model.base = app.ur3.model.fkine(ur3Q) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger1.model.animate(app.ur3finger1.model.getpos);   
                app.ur3finger2.model.base = app.ur3.model.fkine(ur3Q)* trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger2.model.animate(app.ur3finger2.model.getpos); 
      
                tr = app.ur3.model.fkine(ur3Q) * transl(0,0,0.1975); 
                app.bplate_tverts = [app.bplate_verts, ones(size(app.bplate_verts, 1),1)] * tr';
                set(app.blackplate ,'Vertices',app.bplate_tverts(:,1:3)) 

                drawnow();
                pause(0.01);
            end
  
            % Move up after drop
            q8 = deg2rad([182 -23.4 -22 112 90 86.6]);
            q9 = deg2rad([180 -90 0 0 0 0]);
            new_q0 = deg2rad([0 0 0 0 0 0]);

            qWayPoints = [q7;q8;q9;new_q0];
  
            app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));

            app.ur3finger1.model.animate(openMatrix);
            app.ur3finger2.model.animate(openMatrix); % Open fingers
            drawnow();
            
            for i = 1:size(app.qMatrix,1)
                ur3Q = app.qMatrix(i,:);
                app.ur3.model.animate(ur3Q);
                app.ur3finger1.model.base = app.ur3.model.fkine(ur3Q) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger1.model.animate(app.ur3finger2.model.getpos);   
                app.ur3finger2.model.base = app.ur3.model.fkine(ur3Q)* trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
                app.ur3finger2.model.animate(app.ur3finger2.model.getpos);
                drawnow();
            end
           
            app.StatewindowEditField.Value = "Black plate deposited in rack";  
            
             % Set pickup path
             q1 = deg2rad([-45 50 0 -43.2 0 -104 43.5]);
             q2 = deg2rad([-45 55 0 -57.6 0 -107 43.5]);

             qWayPoints = [app.ur30.model.getpos;q1;q2];
  
             app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));

             for i = 1:size(app.qMatrix,1)
                ur30Q = app.qMatrix(i,:);
                app.ur30.model.animate(ur30Q);
                drawnow();
             end
   
            % Pick up and drop plate 
            q3 = deg2rad([-45 50 0 -43.2 0 -104 43.5]); 
            q4 = deg2rad([-131 32 0 -43.2 0 -104 130]);
            q5 = deg2rad([-131 60 0 -43.2 97.2 -144 14]); 

            qWayPoints = [q3;q4;q5];
  
            app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));
  
            for i = 1:size(app.qMatrix,1)
                ur30Q = app.qMatrix(i,:);
                app.ur30.model.animate(ur30Q);
      
                tr = app.ur30.model.fkine(ur30Q) * transl(0,-0.2085,0) * trotz(pi/2) * trotx(-pi/2);
                app.bplate_tverts = [app.bplate_verts, ones(size(app.bplate_verts, 1),1)] * tr';
                set(app.blackplate ,'Vertices',app.bplate_tverts(:,1:3)) 

                drawnow();
                pause(0.01);
            end

            app.bplate_tverts = [app.bplate_verts, ones(size(app.bplate_verts, 1),1)] * transl(-0.725,1.335,2.185)' * trotx(-pi/2);
            set(app.blackplate ,'Vertices',app.bplate_tverts(:,1:3)) 
  
            app.StatewindowEditField.Value = "Black Plate deposited";

            % Repose after drop 
            q7 = deg2rad([-131 32 0 -43.2 0 -104 130]);
            q8 = deg2rad([0 0 0 0 0 0 0]);

            qWayPoints = [q7;q8];
  
            app.qMatrix = InterpolateWayPointRadians(qWayPoints, deg2rad(2));

            for i = 1:size(app.qMatrix,1)
                ur30Q = app.qMatrix(i,:);
                app.ur30.model.animate(ur30Q);
                drawnow();
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetApp();
        end

        % Button pushed function: RMRCControlButton
        function RMRCControlButtonPushed(app, event)
            % Initiate sequence
            app.StatewindowEditField.Value = "Starting RMRC sequence....";
            app.ur3StartQ = [app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]; % Reasonable initial guess for ikcon in control
            app.ur30StartQ = [app.ur30Link1,app.ur30Link2,app.ur30Link3,app.ur30Link4,app.ur30Link5,app.ur30Link6, app.ur30Link7];
            
            % Animate each joint configuration to the set values from the knobs
            app.ur3.model.animate(app.ur3StartQ);
            app.ur30.model.animate(app.ur30StartQ);  

            % RMRC Trajectory
            app.plateHeight = 1.05;
            app.time = 10;                                                  % Total time (s)
            app.deltaT = 0.05;                                              % Control frequency
            app.ur3T1 = app.ur3.model.fkine(app.ur3StartQ);                 % Initial transform
            app.ur30T1 = app.ur30.model.fkine(app.ur30StartQ);
            app.ur3T2 = transl(0.8,-1.4, app.plateHeight) * trotx(pi) * trotz(pi/2);  % Arm Up position (trot to rotate the end-effector orient
 
            app.resolvedMotionRateControl = true;                           % RMRC to Plate Position
            app.ur3control = RMRC(app.ur3, app.resolvedMotionRateControl);
            app.ur3control.ResolvedMotionRateControl(app.ur3T1,app.ur3T2,app.time,app.deltaT);  % towards plate
              
            % From plate to dish rack (ur3)
            ur3_T1update = app.ur3.model.fkine(app.ur3.model.getpos);
            app.ur3T3 = transl(0.1725,-2.0,1.3) * trotx(pi) * trotz(pi/2);
            app.ur3control.ResolvedMotionRateControl(ur3_T1update,app.ur3T3,app.time,app.deltaT);  % towards plate
            
            % Move up after drop
            ur3_T2update = app.ur3.model.fkine(app.ur3.model.getpos);
            app.ur3T4 = transl(0.8,-1.85,2);
            app.ur3control.ResolvedMotionRateControl(ur3_T2update,app.ur3T4,app.time,app.deltaT);  % towards plate
            app.StatewindowEditField.Value = "RMRC sequence finished";
            
            if app.collisionOn
                    if app.ur3control.collisionChecker.checkCollision(app.collisionObj.collisionObjPos)
                        app.EStopButtonPushed;
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GreenPlateButton = uibutton(app.UIFigure, 'push');
            app.GreenPlateButton.ButtonPushedFcn = createCallbackFcn(app, @GreenPlateButtonPushed, true);
            app.GreenPlateButton.Text = 'Green Plate';
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'EStop';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UR3LinksTab = uitab(app.TabGroup);
            app.UR3LinksTab.Title = 'UR3 Links';
            app.Link1KnobLabel = uilabel(app.UR3LinksTab);
            app.Link1KnobLabel.Text = 'Link 1';
            app.Link1Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link1Knob.Limits = [-360 360];
            app.Link1Knob.ValueChangingFcn = createCallbackFcn(app, @Link1KnobValueChanging, true);
            app.Link2KnobLabel = uilabel(app.UR3LinksTab);
            app.Link2KnobLabel.Text = 'Link 2';
            app.Link2Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link2Knob.Limits = [-360 360];
            app.Link2Knob.ValueChangingFcn = createCallbackFcn(app, @Link2KnobValueChanging, true);
            app.Link3KnobLabel = uilabel(app.UR3LinksTab);
            app.Link3KnobLabel.Text = 'Link 3';
            app.Link3Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link3Knob.Limits = [-360 360];
            app.Link3Knob.ValueChangingFcn = createCallbackFcn(app, @Link3KnobValueChanging, true);
            app.Link4KnobLabel = uilabel(app.UR3LinksTab);
            app.Link4KnobLabel.Text = 'Link 4';
            app.Link4Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link4Knob.Limits = [-360 360];
            app.Link4Knob.ValueChangingFcn = createCallbackFcn(app, @Link4KnobValueChanging, true);
            app.Link5KnobLabel = uilabel(app.UR3LinksTab);
            app.Link5KnobLabel.Text = 'Link 5';
            app.Link5Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link5Knob.Limits = [-360 360];
            app.Link5Knob.ValueChangingFcn = createCallbackFcn(app, @Link5KnobValueChanging, true);
            app.Link6KnobLabel = uilabel(app.UR3LinksTab);
            app.Link6KnobLabel.Text = 'Link 6';
            app.Link6Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link6Knob.Limits = [-360 360];
            app.Link6Knob.ValueChangingFcn = createCallbackFcn(app, @Link6KnobValueChanging, true);
            app.UR30LinksTab = uitab(app.TabGroup);
            app.UR30LinksTab.Title = 'UR30 Links';
            app.Link2Knob_2 = uiknob(app.UR30LinksTab, 'continuous');
            app.Link2Knob_2.Limits = [-360 360];
            app.Link2Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link2Knob_2ValueChanging, true);
            app.Link3Knob_2 = uiknob(app.UR30LinksTab, 'continuous');
            app.Link3Knob_2.Limits = [-360 360];
            app.Link3Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link3Knob_2ValueChanging, true);
            app.Link4Knob_2 = uiknob(app.UR30LinksTab, 'continuous');
            app.Link4Knob_2.Limits = [-360 360];
            app.Link4Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link4Knob_2ValueChanging, true);
            app.Link5Knob_2 = uiknob(app.UR30LinksTab, 'continuous');
            app.Link5Knob_2.Limits = [-360 360];
            app.Link5Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link5Knob_2ValueChanging, true);
            app.Link6Knob_2 = uiknob(app.UR30LinksTab, 'continuous');
            app.Link6Knob_2.Limits = [-360 360];
            app.Link6Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link6Knob_2ValueChanging, true);
            app.Link1Knob_2Label = uilabel(app.UR30LinksTab);
            app.Link1Knob_2Label.Text = 'Link 1';
            app.Link1Knob_2 = uiknob(app.UR30LinksTab, 'continuous');
            app.Link1Knob_2.Limits = [-360 360];
            app.Link1Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link1Knob_2ValueChanging, true);
            app.Link2KnobLabel_2 = uilabel(app.UR30LinksTab);
            app.Link2KnobLabel_2.Text = 'Link 2';
            app.Link3KnobLabel_2 = uilabel(app.UR30LinksTab);
            app.Link3KnobLabel_2.Text = 'Link 3';
            app.Link4KnobLabel_2 = uilabel(app.UR30LinksTab);
            app.Link4KnobLabel_2.Text = 'Link 4';
            app.Link5KnobLabel_2 = uilabel(app.UR30LinksTab);
            app.Link5KnobLabel_2.Text = 'Link 5';
            app.Link6KnobLabel_2 = uilabel(app.UR30LinksTab);
            app.Link6KnobLabel_2.Text = 'Link 6';
            app.UR3CartesianControlTab = uitab(app.TabGroup);
            app.UR3CartesianControlTab.Title = 'UR3 Cartesian Control';
            app.XSliderLabel = uilabel(app.UR3CartesianControlTab);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UR3CartesianControlTab);
            app.XSlider.Limits = [-2.5 2.5];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.YSliderLabel = uilabel(app.UR3CartesianControlTab);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UR3CartesianControlTab);
            app.YSlider.Limits = [-2.5 2.5];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.ZSliderLabel = uilabel(app.UR3CartesianControlTab);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UR3CartesianControlTab);
            app.ZSlider.Limits = [-2.5 2.5];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.UR3ECartesianControlTab = uitab(app.TabGroup);
            app.UR3ECartesianControlTab.Title = 'UR3E Cartesian Control';
            app.XSlider_2Label = uilabel(app.UR3ECartesianControlTab);
            app.XSlider_2Label.Text = 'X';
            app.XSlider_2 = uislider(app.UR3ECartesianControlTab);
            app.XSlider_2.Limits = [-2.5 2.5];
            app.XSlider_2.ValueChangingFcn = createCallbackFcn(app, @XSlider_2ValueChanging, true);
            app.YSlider_2Label = uilabel(app.UR3ECartesianControlTab);
            app.YSlider_2Label.Text = 'Y';
            app.YSlider_2 = uislider(app.UR3ECartesianControlTab);
            app.YSlider_2.Limits = [-2.5 2.5];
            app.YSlider_2.ValueChangingFcn = createCallbackFcn(app, @YSlider_2ValueChanging, true);
            app.ZSlider_2Label = uilabel(app.UR3ECartesianControlTab);
            app.ZSlider_2Label.Text = 'Z';
            app.ZSlider_2 = uislider(app.UR3ECartesianControlTab);
            app.ZSlider_2.Limits = [-2.5 2.5];
            app.ZSlider_2.ValueChangingFcn = createCallbackFcn(app, @ZSlider_2ValueChanging, true);
            app.AryztaPicknPackControlPanelLabel = uilabel(app.UIFigure);
            app.AryztaPicknPackControlPanelLabel.Text = 'Aryzta Pick n Pack Control Panel';
            app.ShutDownButton = uibutton(app.UIFigure, 'push');
            app.ShutDownButton.ButtonPushedFcn = createCallbackFcn(app, @ShutDownButtonPushed, true);
            app.ShutDownButton.Text = 'Shut Down';
            app.ControllerStateLamp = uilamp(app.UIFigure);
            app.StatewindowEditField = uieditfield(app.UIFigure, 'text');
            app.EstopTriggerLamp = uilamp(app.UIFigure);
            app.BlackPlateButton = uibutton(app.UIFigure, 'push');
            app.BlackPlateButton.ButtonPushedFcn = createCallbackFcn(app, @BlackPlateButtonPushed, true);
            app.BlackPlateButton.Text = 'Black Plate';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed2, true);
            app.ResumeButton.Text = 'Resume';
            app.CollisionDetectionButton = uibutton(app.UIFigure, 'state');
            app.CollisionDetectionButton.ValueChangedFcn = createCallbackFcn(app, @CollisionDetectionButtonValueChanged, true);
            app.CollisionDetectionButton.Text = 'Collision Detection';
            app.RMRCControlButton = uibutton(app.UIFigure, 'push');
            app.RMRCControlButton.ButtonPushedFcn = createCallbackFcn(app, @RMRCControlButtonPushed, true);
            app.RMRCControlButton.Text = 'RMRC Control';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controlApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end